classdef GUI_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        q1SliderLabel        matlab.ui.control.Label
        q1Slider             matlab.ui.control.Slider
        q2SliderLabel        matlab.ui.control.Label
        q2Slider             matlab.ui.control.Slider
        q3SliderLabel        matlab.ui.control.Label
        q3Slider             matlab.ui.control.Slider
        q4SliderLabel        matlab.ui.control.Label
        q4Slider             matlab.ui.control.Slider
        q5SliderLabel        matlab.ui.control.Label
        q5Slider             matlab.ui.control.Slider
        q6SliderLabel        matlab.ui.control.Label
        q6Slider             matlab.ui.control.Slider
        SpinnerLabel         matlab.ui.control.Label
        Spinner_1            matlab.ui.control.Spinner
        Spinner_2Label       matlab.ui.control.Label
        Spinner_2            matlab.ui.control.Spinner
        Spinner_3Label       matlab.ui.control.Label
        Spinner_3            matlab.ui.control.Spinner
        Spinner_4Label       matlab.ui.control.Label
        Spinner_4            matlab.ui.control.Spinner
        Spinner_5Label       matlab.ui.control.Label
        Spinner_5            matlab.ui.control.Spinner
        Spinner_6Label       matlab.ui.control.Label
        Spinner_6            matlab.ui.control.Spinner
        ArticulacionesLabel  matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        XYZRPYListBoxLabel   matlab.ui.control.Label
        XYZRPYListBox        matlab.ui.control.ListBox
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatePlot(app)
        end

        % Value changing function: q1Slider
        function updatevalueq1(app, event)
            changingValue = event.Value;
            app.Spinner_1.Value=changingValue;
        end

        % Value changing function: q2Slider
        function updatevalueq2(app, event)
            changingValue = event.Value;
            app.Spinner_2.Value=changingValue;
        end

        % Value changing function: q3Slider
        function updatevalueq3(app, event)
            changingValue = event.Value;
            app.Spinner_3.Value=changingValue;
        end

        % Value changing function: q4Slider
        function updatevalueq4(app, event)
            changingValue = event.Value;
            app.Spinner_4.Value=changingValue;
        end

        % Value changing function: q5Slider
        function updatevalueq5(app, event)
            changingValue = event.Value;
            app.Spinner_5.Value=changingValue;
        end

        % Value changing function: q6Slider
        function updatevalueq6(app, event)
            changingValue = event.Value;
            app.Spinner_6.Value=changingValue;
        end

        % Value changing function: Spinner_1
        function updatespinerq1(app, event)
            value = event.Value;
            app.q1Slider.Value = value;
        end

        % Value changing function: Spinner_2
        function updatespinerq2(app, event)
            value = app.Spinner_2.Value;
            app.q2Slider.Value = value;
        end

        % Value changing function: Spinner_3
        function updatespinerq3(app, event)
            value = app.Spinner_3.Value;
            app.q3Slider.Value = value;
        end

        % Value changing function: Spinner_4
        function updatespinerq4(app, event)
            value = event.Value;
            app.q4Slider.Value = value;
        end

        % Value changing function: Spinner_5
        function updatespinerq5(app, event)
            value = app.Spinner_4.Value;
            app.q5Slider.Value = value;
        end

        % Value changing function: Spinner_6
        function updatespinerq6(app, event)
            value = app.Spinner_6.Value;
            app.q6Slider.Value = value;
        end

        % Value changed function: q1Slider, q2Slider, q3Slider, 
        % q4Slider, q5Slider, q6Slider
        function updatePlot(app, event)
            L1 = 330; L2 = 310; L3 = 88; L4 = 305; L5 = 86.5; L6 = 40;
            MTH_10 = (rt2tr(rotx(0,'deg'),[0 0 0]'))*(rt2tr(rotz(app.q1Slider.Value,'deg'),[0 0 L1]'));
            MTH_21 = (rt2tr(rotx(-90,'deg'),[L2 0 0]'))*(rt2tr(rotz(app.q2Slider.Value-90,'deg'),[0 0 0]'));
            MTH_32 = (rt2tr(rotx(0,'deg'),[L3 0 0]'))*(rt2tr(rotz(app.q3Slider.Value,'deg'),[0 0 0]'));
            MTH_43 = (rt2tr(rotx(-90,'deg'),[L4 0 0]'))*(rt2tr(rotz(app.q4Slider.Value,'deg'),[0 0 L5]'));
            MTH_54 = (rt2tr(rotx(90,'deg'),[0 0 0]'))*(rt2tr(rotz(app.q5Slider.Value,'deg'),[0 0 0]'));
            MTH_65 = (rt2tr(rotx(-90,'deg'),[0 0 0]'))*(rt2tr(rotz(app.q6Slider.Value,'deg'),[0 0 L6]'));
            p(:,:,1) = eye(4);
            p(:,:,2) = p(:,:,1)*MTH_10;
            p(:,:,3) = p(:,:,2)*MTH_21;
            p(:,:,4) = p(:,:,3)*MTH_32;
            p(:,:,5) = p(:,:,4)*MTH_43;
            p(:,:,6) = p(:,:,5)*MTH_54;
            p(:,:,7) = p(:,:,6)*MTH_65;
            x=squeeze(p(1,4,:));
            y=squeeze(p(2,4,:));
            z=squeeze(p(3,4,:));
            rot=squeeze(p(1:3,1:3,7));
%             rpy=rad2deg([atan2(rot(3,2),rot(3,3)),atan2(-rot(3,1),sqrt(1-rot(3,1)^2)),atan2(rot(2,1),rot(1,1))])
            rpy=rad2deg(tr2rpy(squeeze(p(:,:,7))));
            lis=[squeeze(p(1:3,4,7))',rpy];
            app.XYZRPYListBox.Items=arrayfun(@num2str,lis,'un',0);
            plot3(app.UIAxes,x,y,z,'LineWidth',6)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {280, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.q1SliderLabel = uilabel(app.LeftPanel);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.LeftPanel);
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @updatevalueq1, true);
            app.q2SliderLabel = uilabel(app.LeftPanel);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.LeftPanel);
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @updatevalueq2, true);
            app.q3SliderLabel = uilabel(app.LeftPanel);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.LeftPanel);
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @updatevalueq3, true);
            app.q4SliderLabel = uilabel(app.LeftPanel);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.LeftPanel);
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @updatevalueq4, true);
            app.q5SliderLabel = uilabel(app.LeftPanel);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.LeftPanel);
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @updatevalueq5, true);
            app.q6SliderLabel = uilabel(app.LeftPanel);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.LeftPanel);
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @updatePlot, true);
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @updatevalueq6, true);
            app.SpinnerLabel = uilabel(app.LeftPanel);
            app.SpinnerLabel.Text = '';
            app.Spinner_1 = uispinner(app.LeftPanel);
            app.Spinner_1.ValueChangingFcn = createCallbackFcn(app, @updatespinerq1, true);
            app.Spinner_2Label = uilabel(app.LeftPanel);
            app.Spinner_2Label.Text = '';
            app.Spinner_2 = uispinner(app.LeftPanel);
            app.Spinner_2.ValueChangingFcn = createCallbackFcn(app, @updatespinerq2, true);
            app.Spinner_3Label = uilabel(app.LeftPanel);
            app.Spinner_3Label.Text = '';
            app.Spinner_3 = uispinner(app.LeftPanel);
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @updatespinerq3, true);
            app.Spinner_4Label = uilabel(app.LeftPanel);
            app.Spinner_4Label.Text = '';
            app.Spinner_4 = uispinner(app.LeftPanel);
            app.Spinner_4.ValueChangingFcn = createCallbackFcn(app, @updatespinerq4, true);
            app.Spinner_5Label = uilabel(app.LeftPanel);
            app.Spinner_5Label.Text = '';
            app.Spinner_5 = uispinner(app.LeftPanel);
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @updatespinerq5, true);
            app.Spinner_6Label = uilabel(app.LeftPanel);
            app.Spinner_6Label.Text = '';
            app.Spinner_6 = uispinner(app.LeftPanel);
            app.Spinner_6.ValueChangingFcn = createCallbackFcn(app, @updatespinerq6, true);
            app.ArticulacionesLabel = uilabel(app.LeftPanel);
            app.ArticulacionesLabel.Text = 'Articulaciones';
            app.RightPanel = uipanel(app.GridLayout);
            app.XYZRPYListBoxLabel = uilabel(app.RightPanel);
            app.XYZRPYListBoxLabel.Text = {'X'; 'Y'; 'Z'; 'R'; 'P'; 'Y'};
            app.XYZRPYListBox = uilistbox(app.RightPanel);
            app.XYZRPYListBox.Items = {'0', '0', '0', '0', '0', '0'};
            app.XYZRPYListBox.Value = '0';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1000 1000];
            app.UIAxes.YLim = [-1000 1000];
            app.UIAxes.ZLim = [0 1000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end